classdef BMI_Calc_App_zip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditField_2           matlab.ui.control.EditField
        Label                 matlab.ui.control.Label
        BMI40EditField        matlab.ui.control.EditField
        BMI40EditFieldLabel   matlab.ui.control.Label
        EditField_3           matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        BMI184EditField       matlab.ui.control.EditField
        BMI184EditFieldLabel  matlab.ui.control.Label
        EditField2            matlab.ui.control.EditField
        EditField             matlab.ui.control.EditField
        BMIEditField          matlab.ui.control.NumericEditField
        BMIEditFieldLabel     matlab.ui.control.Label
        UNITSDropDown         matlab.ui.control.DropDown
        UNITSDropDownLabel    matlab.ui.control.Label
        WeightEditField       matlab.ui.control.NumericEditField
        WeightEditFieldLabel  matlab.ui.control.Label
        HeightEditField       matlab.ui.control.NumericEditField
        HeightEditFieldLabel  matlab.ui.control.Label
        CalculateBMIButton    matlab.ui.control.Button
        BMICalculatorLabel    matlab.ui.control.Label
    end

    %App Startup function
    methods (Access = private)
     
        % Startup function: App startup

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BMIEditField
        function BMIEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: CalculateBMIButton
        function CalculateBMIButtonPushed(app, event)
            %First, get values from the height and weight fields
            h = app.HeightEditField.Value;
            w = app.WeightEditField.Value;

            %Next, Get the selected measurement unit(standard/metric) from
            %dropdown menu
            units = app.UNITSDropDown.Value;


            %Perform BMI calculation based on height/weight and unit
            %selection
            if strcmp(units, 'Standard(lbs/in.)')
                result = (w/(h^2))*703
            elseif strcmp(units, 'Metric(kg/cm)')
                result = (w/((h/100)^2))
            end
       %display the result
       app.BMIEditField.Value = result


        end

        % Value changed function: UNITSDropDown
        function UNITSDropDownValueChanged(app, event)
            value = app.UNITSDropDown.Value 

            if strcmp(value,'Standard(lbs/in.)')
                app.EditField.Value = 'Pounds'
                app.EditField2.Value = 'Inches'
            elseif strcmp(value, 'Metric(kg/cm)')
                app.EditField.Value = 'Kilograms'
                app.EditField2.Value = 'Centimeters'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BMICalculatorLabel = uilabel(app.UIFigure);
            app.BMICalculatorLabel.Text = 'BMI Calculator';
            app.CalculateBMIButton = uibutton(app.UIFigure, 'push');
            app.CalculateBMIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBMIButtonPushed, true);
            app.CalculateBMIButton.Text = 'Calculate BMI';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.UNITSDropDownLabel = uilabel(app.UIFigure);
            app.UNITSDropDownLabel.Text = 'UNITS';
            app.UNITSDropDown = uidropdown(app.UIFigure);
            app.UNITSDropDown.Items = {'*CHOOSE*', 'Standard(lbs/in.)', 'Metric(kg/cm)'};
            app.UNITSDropDown.ValueChangedFcn = createCallbackFcn(app, @UNITSDropDownValueChanged, true);
            app.UNITSDropDown.Value = '*CHOOSE*';
            app.BMIEditFieldLabel = uilabel(app.UIFigure);
            app.BMIEditFieldLabel.Text = 'BMI:';
            app.BMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMIEditField.ValueChangedFcn = createCallbackFcn(app, @BMIEditFieldValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.BMI184EditFieldLabel = uilabel(app.UIFigure);
            app.BMI184EditFieldLabel.Text = 'BMI<18.4';
            app.BMI184EditField = uieditfield(app.UIFigure, 'text');
            app.BMI184EditField.Value = 'Underweight';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '25 - 39.9';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Overweight';
            app.BMI40EditFieldLabel = uilabel(app.UIFigure);
            app.BMI40EditFieldLabel.Text = 'BMI>40';
            app.BMI40EditField = uieditfield(app.UIFigure, 'text');
            app.BMI40EditField.Value = 'Obese';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '18.5 - 24.9';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Normal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMI_Calc_App_zip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end