classdef Kinematicsgraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InitialVelocitymsLabel         matlab.ui.control.Label
        DisplacementmEditField         matlab.ui.control.NumericEditField
        DisplacementmEditFieldLabel    matlab.ui.control.Label
        PlotButton_2                   matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        ColorButtonGroup               matlab.ui.container.ButtonGroup
        RedButton                      matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        BlueButton_2                   matlab.ui.control.RadioButton
        Accelerationms2EditField       matlab.ui.control.NumericEditField
        Accelerationms2EditFieldLabel  matlab.ui.control.Label
        InitialVelocitymsEditField     matlab.ui.control.NumericEditField
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        h % Description
        k % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            A=app.Accelerationms2EditField.Value;
            V=app.InitialVelocitymsEditField.Value;
            time=-50:0.01:50;
            d=(V*time)+(0.5*A*(time.^2));
            app.h=plot(app.UIAxes,time,d);
            app.h.LineWidth=2.5;
        end

        % Selection changed function: ColorButtonGroup
        function ColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorButtonGroup.SelectedObject;
            radioColor=selectedButton.Text
            app.h.Color=radioColor
            app.k.Color=radioColor
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            A=app.Accelerationms2EditField.Value;
            V=app.InitialVelocitymsEditField.Value;
            D=app.DisplacementmEditField.Value;
            time=-50:0.01:50;
            V_f=V+(A*time);
            app.k=plot(app.UIAxes_2,time,V_f);
            app.k.LineWidth=2.5;
        end

        % Callback function: not associated with a component
        function LineTypeButtonGroupSelectionChanged(app, event)
            app.LineTypeButtonGroup.ItemsData={'-','--',':','.'}
            value=app.LineTypeButtonGroup.Value;
            app.h.LinesType=value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kinematics Distance')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Distance(m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Kinematics Final Velocity')
            xlabel(app.UIAxes_2, 'Time(s)')
            ylabel(app.UIAxes_2, 'Final Velocity(m/s)')
            zlabel(app.UIAxes_2, 'Z')
            app.InitialVelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialVelocitymsEditField.Tag = 'Hsldkhfslkg';
            app.Accelerationms2EditFieldLabel = uilabel(app.UIFigure);
            app.Accelerationms2EditFieldLabel.Text = 'Acceleration(m/s^2)';
            app.Accelerationms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorButtonGroupSelectionChanged, true);
            app.ColorButtonGroup.Title = 'Color';
            app.BlueButton_2 = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton_2.Text = 'Blue';
            app.BlueButton_2.Value = true;
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.DisplacementmEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementmEditFieldLabel.Text = 'Displacement(m)';
            app.DisplacementmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialVelocitymsLabel = uilabel(app.UIFigure);
            app.InitialVelocitymsLabel.Text = 'Initial Velocity(m/s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kinematicsgraph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end