classdef TicTacToeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        OverallFormat       matlab.ui.container.GridLayout
        SwitchFormat        matlab.ui.container.GridLayout
        WhenaWinnerisRecognizedPressthisButton  matlab.ui.control.Button
        PlayerFormat        matlab.ui.container.GridLayout
        PlayerSwitch        matlab.ui.control.Switch
        PlayerSwitchLabel   matlab.ui.control.Label
        ModeSwitchFormat    matlab.ui.container.GridLayout
        ModeSwitch          matlab.ui.control.Switch
        ModeSwitchLabel     matlab.ui.control.Label
        ButtonFormat        matlab.ui.container.GridLayout
        BottomRightButton   matlab.ui.control.Button
        BottomMiddleButton  matlab.ui.control.Button
        BottomLeftButton    matlab.ui.control.Button
        MiddleRightButton   matlab.ui.control.Button
        MiddleCenterButton  matlab.ui.control.Button
        MiddleLeftButton    matlab.ui.control.Button
        TopRightButton      matlab.ui.control.Button
        TopMiddleButton     matlab.ui.control.Button
        TopLeftButton       matlab.ui.control.Button
        TicTacToeLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
             switch app.ModeSwitch.Value
                case 'Dark' 
                app.OverallFormat.BackgroundColor = [0.25 0.25 0.25];
                case 'Light'
                app.OverallFormat.BackgroundColor = [1 1 1];
            end
        end

        % Button pushed function: BottomRightButton
        function BottomRightButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.BottomRightButton.Text = 'X';
                case 'O'
                app.BottomRightButton.Text = 'O';
            end
        end

        % Button pushed function: BottomMiddleButton
        function BottomMiddleButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.BottomMiddleButton.Text = 'X';
                case 'O'
                app.BottomMiddleButton.Text = 'O';
            end
        end

        % Button pushed function: BottomLeftButton
        function BottomLeftButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.BottomLeftButton.Text = 'X';
                case 'O'
                app.BottomLeftButton.Text = 'O';
            end
        end

        % Button pushed function: MiddleRightButton
        function MiddleRightButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.MiddleRightButton.Text = 'X';
                case 'O'
                app.MiddleRightButton.Text = 'O';
            end
        end

        % Button pushed function: MiddleCenterButton
        function MiddleCenterButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.MiddleCenterButton.Text = 'X';
                case 'O'
                app.MiddleCenterButton.Text = 'O';
            end
        end

        % Button pushed function: MiddleLeftButton
        function MiddleLeftButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.MiddleLeftButton.Text = 'X';
                case 'O'
                app.MiddleLeftButton.Text = 'O';
            end
        end

        % Button pushed function: TopRightButton
        function TopRightButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.TopRightButton.Text = 'X';
                case 'O'
                app.TopRightButton.Text = 'O';
            end
        end

        % Button pushed function: TopMiddleButton
        function TopMiddleButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.TopMiddleButton.Text = 'X';
                case 'O'
                app.TopMiddleButton.Text = 'O';
            end
        end

        % Button pushed function: TopLeftButton
        function TopLeftButtonPushed(app, event)
            switch app.PlayerSwitch.Value
                case 'X' 
                app.TopLeftButton.Text = 'X';
                case 'O'
                app.TopLeftButton.Text = 'O';
            end
        end

        % Button pushed function: WhenaWinnerisRecognizedPressthisButton
        function WhenaWinnerisRecognizedPressthisButtonPushed(app, event)
            app.TopLeftButton.Text = 'Top Left';
            app.TopMiddleButton.Text = 'Top Middle';
            app.TopRightButton.Text = 'Top Right';
            app.MiddleLeftButton.Text = 'Middle Left';
            app.MiddleCenterButton.Text = 'Middle Center';
            app.MiddleRightButton.Text = 'Middle Right';
            app.BottomLeftButton.Text = 'Bottom Left';
            app.BottomMiddleButton.Text = 'Bottom Middle';
            app.BottomRightButton.Text = 'Bottom Right';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OverallFormat = uigridlayout(app.UIFigure);
            app.TicTacToeLabel = uilabel(app.OverallFormat);
            app.TicTacToeLabel.Text = 'Tic - Tac - Toe';
            app.ButtonFormat = uigridlayout(app.OverallFormat);
            app.TopLeftButton = uibutton(app.ButtonFormat, 'push');
            app.TopLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TopLeftButtonPushed, true);
            app.TopLeftButton.Text = 'Top Left';
            app.TopMiddleButton = uibutton(app.ButtonFormat, 'push');
            app.TopMiddleButton.ButtonPushedFcn = createCallbackFcn(app, @TopMiddleButtonPushed, true);
            app.TopMiddleButton.Text = 'Top Middle';
            app.TopRightButton = uibutton(app.ButtonFormat, 'push');
            app.TopRightButton.ButtonPushedFcn = createCallbackFcn(app, @TopRightButtonPushed, true);
            app.TopRightButton.Text = 'Top Right';
            app.MiddleLeftButton = uibutton(app.ButtonFormat, 'push');
            app.MiddleLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MiddleLeftButtonPushed, true);
            app.MiddleLeftButton.Text = 'Middle Left';
            app.MiddleCenterButton = uibutton(app.ButtonFormat, 'push');
            app.MiddleCenterButton.ButtonPushedFcn = createCallbackFcn(app, @MiddleCenterButtonPushed, true);
            app.MiddleCenterButton.Text = 'Middle Center';
            app.MiddleRightButton = uibutton(app.ButtonFormat, 'push');
            app.MiddleRightButton.ButtonPushedFcn = createCallbackFcn(app, @MiddleRightButtonPushed, true);
            app.MiddleRightButton.Text = 'Middle Right';
            app.BottomLeftButton = uibutton(app.ButtonFormat, 'push');
            app.BottomLeftButton.ButtonPushedFcn = createCallbackFcn(app, @BottomLeftButtonPushed, true);
            app.BottomLeftButton.Text = 'Bottom Left';
            app.BottomMiddleButton = uibutton(app.ButtonFormat, 'push');
            app.BottomMiddleButton.ButtonPushedFcn = createCallbackFcn(app, @BottomMiddleButtonPushed, true);
            app.BottomMiddleButton.Text = 'Bottom Middle';
            app.BottomRightButton = uibutton(app.ButtonFormat, 'push');
            app.BottomRightButton.ButtonPushedFcn = createCallbackFcn(app, @BottomRightButtonPushed, true);
            app.BottomRightButton.Text = 'Bottom Right';
            app.SwitchFormat = uigridlayout(app.OverallFormat);
            app.ModeSwitchFormat = uigridlayout(app.SwitchFormat);
            app.ModeSwitchLabel = uilabel(app.ModeSwitchFormat);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.ModeSwitchFormat, 'slider');
            app.ModeSwitch.Items = {'Light', 'Dark'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Light';
            app.PlayerFormat = uigridlayout(app.SwitchFormat);
            app.PlayerSwitchLabel = uilabel(app.PlayerFormat);
            app.PlayerSwitchLabel.Text = 'Player';
            app.PlayerSwitch = uiswitch(app.PlayerFormat, 'slider');
            app.PlayerSwitch.Items = {'X', 'O'};
            app.PlayerSwitch.Value = 'X';
            app.WhenaWinnerisRecognizedPressthisButton = uibutton(app.SwitchFormat, 'push');
            app.WhenaWinnerisRecognizedPressthisButton.ButtonPushedFcn = createCallbackFcn(app, @WhenaWinnerisRecognizedPressthisButtonPushed, true);
            app.WhenaWinnerisRecognizedPressthisButton.Text = {'When a Winner is '; 'Recognized'; 'Press this Button'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end