classdef UnitConverterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputAmountEditField_5        matlab.ui.control.NumericEditField
        InputAmountEditField_5Label   matlab.ui.control.Label
        OutputAmountEditField_4       matlab.ui.control.NumericEditField
        OutputAmountEditField_4Label  matlab.ui.control.Label
        InputAmountEditField_4        matlab.ui.control.NumericEditField
        InputAmountEditField_4Label   matlab.ui.control.Label
        MetricDropDown_2              matlab.ui.control.DropDown
        MetricDropDown_2Label         matlab.ui.control.Label
        MetricDropDown                matlab.ui.control.DropDown
        MetricDropDownLabel           matlab.ui.control.Label
        UNITCONVERTERLabel            matlab.ui.control.Label
        StandardDropDown_2            matlab.ui.control.DropDown
        OutputDropDown_5              matlab.ui.control.DropDown
        OutputDropDown_6              matlab.ui.control.DropDown
        OutputAmountEditField         matlab.ui.control.NumericEditField
        OutputAmountEditField_2       matlab.ui.control.NumericEditField
        OutputAmountEditField_3       matlab.ui.control.NumericEditField
        InputAmountEditField          matlab.ui.control.NumericEditField
        InputAmountEditField_3        matlab.ui.control.NumericEditField
        OutputDropDown_6Label         matlab.ui.control.Label
        OutputDropDown_5Label         matlab.ui.control.Label
        StandardDropDown_2Label       matlab.ui.control.Label
        StandardDropDown              matlab.ui.control.DropDown
        InputDropDown_2               matlab.ui.control.DropDown
        InputDropDown_3               matlab.ui.control.DropDown
        VolumeLabel                   matlab.ui.control.Label
        WeightLabel                   matlab.ui.control.Label
        LengthLabel                   matlab.ui.control.Label
        ConvertButton_4               matlab.ui.control.Button
        OutputAmountEditField_3Label  matlab.ui.control.Label
        InputAmountEditField_3Label   matlab.ui.control.Label
        InputDropDown_3Label          matlab.ui.control.Label
        OutputAmountEditFieldLabel    matlab.ui.control.Label
        InputDropDown_2Label          matlab.ui.control.Label
        OutputAmountEditField_2Label  matlab.ui.control.Label
        InputAmountEditFieldLabel     matlab.ui.control.Label
        StandardDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputAmountEditField_3
        function InputAmountEditField_3ValueChanged(app, event)
            value = app.InputAmountEditField_3.Value;
         if app.InputAmountEditField_3.Value > 0
            if app.InputDropDown_3.Value == 'ml'
                v= value;
            elseif app.InputDropDown_3.Value == "L"
                v= value.*1000;
            elseif app.InputDropDown_3.Value =='in'
                v= value.*16.387;
            elseif app.InputDropDown_3.Value == 'ft'
                v= value.*28320;
            end
             if app.OutputDropDown_6.Value == 'ml'
                   app.OutputAmountEditField_3.Value=v;
             elseif app.OutputDropDown_6.Value == 'L'
                    app.OutputAmountEditField_3.Value = v./1000;
             elseif app.OutputDropDown_6.Value == 'in'
                    app.OutputAmountEditField_3.Value = v./16.387;
             elseif app.OutputDropDown_6.Value == 'ft'
                    app.OutputAmountEditField_3.Value = v./28320;
             end
         end
        end

        % Value changed function: InputAmountEditField
        function InputAmountEditFieldValueChanged(app, event)
            value1 = app.InputAmountEditField.Value;
            if app.InputAmountEditField.Value>0
            if app.StandardDropDown.Value == 'Inch'
                v= value1;
            elseif app.StandardDropDown.Value == "Foot"
                v= value1.*12;
            elseif app.StandardDropDown.Value =='Yard'
                v= value1.*36;
            elseif app.StandardDropDown.Value == 'Mile'
                v= value1.*63360;
            end
                if app.StandardDropDown_2.Value == 'Inch'
                   app.OutputAmountEditField_2.Value=v;
                elseif app.StandardDropDown_2.Value == 'Foot'
                    app.OutputAmountEditField_2.Value = v./12;
                elseif app.StandardDropDown_2.Value == 'Yard'
                    app.OutputAmountEditField_2.Value = v./36;
                elseif app.StandardDropDown_2.Value == 'Mile'
                    app.OutputAmountEditField_2.Value = v./63360;
                end
                if app.MetricDropDown_2.Value == 'mm'
                    app.OutputAmountEditField_4.Value = v.*25.4;
                elseif app.MetricDropDown_2.Value == 'cm'
                    app.OutputAmountEditField_4.Value = v.*2.54;
                elseif app.MetricDropDown_2.Value == 'M'
                    app.OutputAmountEditField_4.Value = v.*.0254;
                elseif app.MetricDropDown_2.Value == 'km'
                    app.OutputAmountEditField_4.Value = v./39370;
                end
            end


        end

        % Value changed function: InputAmountEditField_4
        function InputAmountEditField_4ValueChanged(app, event)
            value2 = app.InputAmountEditField_4.Value;
            
            if app.InputAmountEditField_4.Value >0
                if app.MetricDropDown.Value == 'mm'
                    v= value2./25.4;
                elseif app.MetricDropDown.Value == 'cm'
                    v= value2./2.54;
                elseif app.MetricDropDown.Value == 'M'
                    v = value2./.0254;
                elseif app.MetricDropDown.Value == 'km'
                    v = value2.*39370;
                end
                
                if app.MetricDropDown_2.Value == 'mm'
                    app.OutputAmountEditField_4.Value = v.*25.4;
                elseif app.MetricDropDown_2.Value == 'cm'
                    app.OutputAmountEditField_4.Value = v.*2.54;
                elseif app.MetricDropDown_2.Value == 'M'
                    app.OutputAmountEditField_4.Value = v.*.0254;
                elseif app.MetricDropDown_2.Value == 'km'
                    app.OutputAmountEditField_4.Value = v./39370;
                end
                if app.StandardDropDown_2.Value == 'Inch'
                   app.OutputAmountEditField_2.Value=v;
                elseif app.StandardDropDown_2.Value == 'Foot'
                    app.OutputAmountEditField_2.Value = v./12;
                elseif app.StandardDropDown_2.Value == 'Yard'
                    app.OutputAmountEditField_2.Value = v./36;
                elseif app.StandardDropDown_2.Value == 'Mile'
                    app.OutputAmountEditField_2.Value = v./63360;
                end

            end
        end

        % Value changed function: InputAmountEditField_5
        function InputAmountEditField_5ValueChanged(app, event)
            value = app.InputAmountEditField_5.Value;
            if value > 0
                if app.InputDropDown_2.Value == 'g'
                    v2 = value
                elseif app.InputDropDown_2.Value == 'kg'
                    v2 = value.*1000
                elseif app.InputDropDown_2.Value == 'oz'
                    v2 = value.*28.35
                elseif app.InputDropDown_2.Value == 'lb'
                    v2 = value.*453.6
                end
                if app.OutputDropDown_5.Value == 'g'
                    app.OutputAmountEditField.Value = v2
                elseif app.OutputDropDown_5.Value == 'kg'
                    app.OutputAmountEditField.Value = v2./1000
                elseif app.OutputDropDown_5.Value == 'oz'
                    app.OutputAmountEditField.Value = v2./28.35
                elseif app.OutputDropDown_5.Value == 'lb'
                    app.OutputAmountEditField.Value = v2./453.6
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StandardDropDownLabel = uilabel(app.UIFigure);
            app.StandardDropDownLabel.Text = 'Standard';
            app.InputAmountEditFieldLabel = uilabel(app.UIFigure);
            app.InputAmountEditFieldLabel.Text = 'Input Amount';
            app.OutputAmountEditField_2Label = uilabel(app.UIFigure);
            app.OutputAmountEditField_2Label.Text = 'Output Amount';
            app.InputDropDown_2Label = uilabel(app.UIFigure);
            app.InputDropDown_2Label.Text = 'Input';
            app.OutputAmountEditFieldLabel = uilabel(app.UIFigure);
            app.OutputAmountEditFieldLabel.Text = 'Output Amount';
            app.InputDropDown_3Label = uilabel(app.UIFigure);
            app.InputDropDown_3Label.Text = 'Input';
            app.InputAmountEditField_3Label = uilabel(app.UIFigure);
            app.InputAmountEditField_3Label.Text = 'Input Amount';
            app.OutputAmountEditField_3Label = uilabel(app.UIFigure);
            app.OutputAmountEditField_3Label.Text = 'Output Amount';
            app.ConvertButton_4 = uibutton(app.UIFigure, 'push');
            app.ConvertButton_4.Text = 'Convert';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.WeightLabel = uilabel(app.UIFigure);
            app.WeightLabel.Text = 'Weight';
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Text = 'Volume';
            app.InputDropDown_3 = uidropdown(app.UIFigure);
            app.InputDropDown_3.Items = {'ml', 'L', 'in', 'ft'};
            app.InputDropDown_3.Value = 'ml';
            app.InputDropDown_2 = uidropdown(app.UIFigure);
            app.InputDropDown_2.Items = {'oz', 'lb', 'g', 'kg'};
            app.InputDropDown_2.Value = 'oz';
            app.StandardDropDown = uidropdown(app.UIFigure);
            app.StandardDropDown.Items = {'Inch', 'Foot', 'Yard', 'Mile'};
            app.StandardDropDown.Value = 'Inch';
            app.StandardDropDown_2Label = uilabel(app.UIFigure);
            app.StandardDropDown_2Label.Text = 'Standard';
            app.OutputDropDown_5Label = uilabel(app.UIFigure);
            app.OutputDropDown_5Label.Text = 'Output';
            app.OutputDropDown_6Label = uilabel(app.UIFigure);
            app.OutputDropDown_6Label.Text = 'Output';
            app.InputAmountEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.InputAmountEditField_3.ValueChangedFcn = createCallbackFcn(app, @InputAmountEditField_3ValueChanged, true);
            app.InputAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputAmountEditField.ValueChangedFcn = createCallbackFcn(app, @InputAmountEditFieldValueChanged, true);
            app.OutputAmountEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.OutputAmountEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.OutputAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputDropDown_6 = uidropdown(app.UIFigure);
            app.OutputDropDown_6.Items = {'ml', 'L', 'in', 'ft'};
            app.OutputDropDown_6.Value = 'ml';
            app.OutputDropDown_5 = uidropdown(app.UIFigure);
            app.OutputDropDown_5.Items = {'oz', 'lb', 'g', 'kg'};
            app.OutputDropDown_5.Value = 'oz';
            app.StandardDropDown_2 = uidropdown(app.UIFigure);
            app.StandardDropDown_2.Items = {'Inch', 'Foot', 'Yard', 'Mile'};
            app.StandardDropDown_2.Value = 'Inch';
            app.UNITCONVERTERLabel = uilabel(app.UIFigure);
            app.UNITCONVERTERLabel.Text = 'UNIT CONVERTER';
            app.MetricDropDownLabel = uilabel(app.UIFigure);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.UIFigure);
            app.MetricDropDown.Items = {'mm', 'cm', 'M', 'km'};
            app.MetricDropDown.Value = 'mm';
            app.MetricDropDown_2Label = uilabel(app.UIFigure);
            app.MetricDropDown_2Label.Text = 'Metric';
            app.MetricDropDown_2 = uidropdown(app.UIFigure);
            app.MetricDropDown_2.Items = {'mm', 'cm', 'M', 'km'};
            app.MetricDropDown_2.Value = 'mm';
            app.InputAmountEditField_4Label = uilabel(app.UIFigure);
            app.InputAmountEditField_4Label.Text = 'Input Amount';
            app.InputAmountEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.InputAmountEditField_4.ValueChangedFcn = createCallbackFcn(app, @InputAmountEditField_4ValueChanged, true);
            app.OutputAmountEditField_4Label = uilabel(app.UIFigure);
            app.OutputAmountEditField_4Label.Text = 'Output Amount';
            app.OutputAmountEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.InputAmountEditField_5Label = uilabel(app.UIFigure);
            app.InputAmountEditField_5Label.Text = 'Input Amount';
            app.InputAmountEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.InputAmountEditField_5.ValueChangedFcn = createCallbackFcn(app, @InputAmountEditField_5ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnitConverterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end