classdef Unit_Converter_1_zip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConvertButton_3         matlab.ui.control.Button
        ConvertButton_2         matlab.ui.control.Button
        ConvertButton           matlab.ui.control.Button
        OutputEditField_3       matlab.ui.control.NumericEditField
        OutputEditField_3Label  matlab.ui.control.Label
        ToDropDown_3            matlab.ui.control.DropDown
        ToDropDown_3Label       matlab.ui.control.Label
        InputEditField_3        matlab.ui.control.NumericEditField
        InputEditField_3Label   matlab.ui.control.Label
        FromDropDown_3          matlab.ui.control.DropDown
        FromDropDown_3Label     matlab.ui.control.Label
        OutputEditField_2       matlab.ui.control.NumericEditField
        OutputEditField_2Label  matlab.ui.control.Label
        ToDropDown_2            matlab.ui.control.DropDown
        ToDropDown_2Label       matlab.ui.control.Label
        InputEditField_2        matlab.ui.control.NumericEditField
        InputEditField_2Label   matlab.ui.control.Label
        FromDropDown_2          matlab.ui.control.DropDown
        FromDropDown_2Label     matlab.ui.control.Label
        TemperatureLabel        matlab.ui.control.Label
        WeightLabel             matlab.ui.control.Label
        ToDropDown              matlab.ui.control.DropDown
        ToDropDownLabel         matlab.ui.control.Label
        OutputEditField         matlab.ui.control.NumericEditField
        OutputEditFieldLabel    matlab.ui.control.Label
        InputEditField          matlab.ui.control.NumericEditField
        InputEditFieldLabel     matlab.ui.control.Label
        FromDropDown            matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        LengthLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            %retrieve first length value
            l1 = app.InputEditField.Value
            
            %retrive unit from dropdown
            units = app.FromDropDown.Value
            units2 = app.ToDropDown.Value


            %perform conversion
            if strcmp(units, 'Feet')
                if strcmp(units2, 'Meters')
                    result = l1/3.281
                elseif strcmp(units2, 'Centimeters')
                    result= l1*30.48
                elseif strcmp(units2, 'Feet')
                    result= l1*1
                else strcmp(units2, 'Inches')
                    result= l1*12
                end
            elseif strcmp(units, 'Meters')
                if strcmp(units2, 'Feet')
                result = l1 * 3.281
                elseif strcmp(units,'Centimeters')
                    result= l1*100
                elseif strcmp(units2, 'Meters')
                    result= l1
                else strcmp(units2, 'Inches')
                    result= l1*39.37
                end
            elseif strcmp(units, 'Centimeters')
                if strcmp(units2, 'Feet')
                    result= l1/30.48
                elseif strcmp(units2, 'Inches')
                    result= l1/2.54
                elseif strcmp(units2, 'Centimeters')
                    result= l1
                else strcmp(units2, 'Meters')
                    result= l1/100
                end
            else strcmp(units, 'Inches')
                if strcmp(units2, 'Feet')
                    result= l1/12
                elseif strcmp(units2, 'Meters')
                    result= l1/39.37
                elseif strcmp(units2, 'Inches')
                    result= l1
                else strcmp(units2, 'Centimeters')
                    result= l1*2.54
                end
            end
            app.OutputEditField.Value = result
        end

        % Clicked callback: FromDropDown
        function FromDropDownClicked(app, event)
            
        end

        % Button pushed function: ConvertButton_3
        function ConvertButton_3Pushed(app, event)
            l1= app.InputEditField_3.Value
            units = app.FromDropDown_3.Value
            units2 = app.ToDropDown_3.Value
            
            % Perform Conversion
            if strcmp(units, 'Fahrenheit')
                if strcmp(units2, 'Celsius')
                result = (l1-32)*(5/9)
                elseif strcmp(units2, 'Kelvin')
                result = ((l1-32)*(5/9)) + 273
                else strcmp(units2, 'Fahrenheit')
                    result= l1*1
                end
            elseif strcmp(units, 'Celsius')
                if strcmp(units2, 'Fahrenheit')
                result = (l1*(9/5))+32
                elseif strcmp(units2, 'Kelvin')
                result = l1 + 273
                else strcmp(units2,'Celsius')
                    result= l1*1
                end
            else strcmp(units, 'Kelvin')
                if strcmp(units2, 'Fahrenheit')
                result = (l1-273)*(9/5)+32
                elseif strcmp(units2, 'Celsius')
                result = l1 - 273
                else strcmp(units2, 'Kelvin')
                    result= l1*1
                end
            end
            app.OutputEditField_3.Value = result
        end

        % Clicked callback: FromDropDown_3
        function FromDropDown_3Clicked(app, event)

        end

        % Button pushed function: ConvertButton_2
        function ConvertButton_2Pushed(app, event)
            l1 = app.InputEditField_2.Value
            units = app.FromDropDown_2.Value
            units2 = app.ToDropDown_2.Value

            %Perform conversion
            if strcmp(units,'Pounds')
              if strcmp(units2,'Kilograms')
                  result = l1/2.205 
              elseif strcmp(units2, 'Ounces')
                  result= l1*16
              elseif strcmp(units2, 'Grams')
                  result= l1*453.6
              else strcmp(units2, 'Pounds')
                  result= l1
              end
            elseif strcmp(units, 'Kilograms')
                if strcmp(units2,'Kilograms')
                  result = l1
              elseif strcmp(units2, 'Ounces')
                  result= l1*35.274
              elseif strcmp(units2, 'Grams')
                  result= l1*1000
              else strcmp(units2, 'Pounds')
                  result= l1*2.205
              end
            elseif strcmp(units, 'Grams')
                if strcmp(units2,'Kilograms')
                  result = l1/1000
              elseif strcmp(units2, 'Ounces')
                  result= l1/28.35
              elseif strcmp(units2, 'Grams')
                  result= l1
              else strcmp(units2, 'Pounds')
                  result= l1/453.6
              end
            else strcmp(units, 'Ounces')
                if strcmp(units2,'Kilograms')
                  result = l1/35.274
              elseif strcmp(units2, 'Ounces')
                  result= l1
              elseif strcmp(units2, 'Grams')
                  result= l1*28.35
              else strcmp(units2, 'Pounds')
                  result= l1/16
              end
            end
            app.OutputEditField_2.Value = result
           
        end

        % Clicked callback: FromDropDown_2
        function FromDropDown_2Clicked(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.FromDropDownLabel = uilabel(app.UIFigure);
            app.FromDropDownLabel.Text = 'From';
            app.FromDropDown = uidropdown(app.UIFigure);
            app.FromDropDown.Items = {'Feet', 'Meters', 'Centimeters', 'Inches'};
            app.FromDropDown.ClickedFcn = createCallbackFcn(app, @FromDropDownClicked, true);
            app.FromDropDown.Value = 'Feet';
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToDropDownLabel = uilabel(app.UIFigure);
            app.ToDropDownLabel.Text = 'To';
            app.ToDropDown = uidropdown(app.UIFigure);
            app.ToDropDown.Items = {'Meters', 'Feet', 'Inches', 'Centimeters'};
            app.ToDropDown.Value = 'Meters';
            app.WeightLabel = uilabel(app.UIFigure);
            app.WeightLabel.Text = 'Weight';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.FromDropDown_2Label = uilabel(app.UIFigure);
            app.FromDropDown_2Label.Text = 'From';
            app.FromDropDown_2 = uidropdown(app.UIFigure);
            app.FromDropDown_2.Items = {'Pounds', 'Kilograms', 'Ounces', 'Grams'};
            app.FromDropDown_2.ClickedFcn = createCallbackFcn(app, @FromDropDown_2Clicked, true);
            app.FromDropDown_2.Value = 'Pounds';
            app.InputEditField_2Label = uilabel(app.UIFigure);
            app.InputEditField_2Label.Text = 'Input';
            app.InputEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ToDropDown_2Label = uilabel(app.UIFigure);
            app.ToDropDown_2Label.Text = 'To';
            app.ToDropDown_2 = uidropdown(app.UIFigure);
            app.ToDropDown_2.Items = {'Kilograms', 'Pounds', 'Ounces', 'Grams'};
            app.ToDropDown_2.Value = 'Kilograms';
            app.OutputEditField_2Label = uilabel(app.UIFigure);
            app.OutputEditField_2Label.Text = 'Output';
            app.OutputEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FromDropDown_3Label = uilabel(app.UIFigure);
            app.FromDropDown_3Label.Text = 'From';
            app.FromDropDown_3 = uidropdown(app.UIFigure);
            app.FromDropDown_3.Items = {'Fahrenheit', 'Celsius', 'Kelvin'};
            app.FromDropDown_3.ClickedFcn = createCallbackFcn(app, @FromDropDown_3Clicked, true);
            app.FromDropDown_3.Value = 'Fahrenheit';
            app.InputEditField_3Label = uilabel(app.UIFigure);
            app.InputEditField_3Label.Text = 'Input';
            app.InputEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ToDropDown_3Label = uilabel(app.UIFigure);
            app.ToDropDown_3Label.Text = 'To';
            app.ToDropDown_3 = uidropdown(app.UIFigure);
            app.ToDropDown_3.Items = {'Celsius', 'Fahrenheit', 'Kelvin'};
            app.ToDropDown_3.Value = 'Celsius';
            app.OutputEditField_3Label = uilabel(app.UIFigure);
            app.OutputEditField_3Label.Text = 'Output';
            app.OutputEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.ConvertButton_2 = uibutton(app.UIFigure, 'push');
            app.ConvertButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConvertButton_2Pushed, true);
            app.ConvertButton_2.Text = 'Convert';
            app.ConvertButton_3 = uibutton(app.UIFigure, 'push');
            app.ConvertButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConvertButton_3Pushed, true);
            app.ConvertButton_3.Text = 'Convert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Unit_Converter_1_zip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end