classdef vector_decomp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        XButton                   matlab.ui.control.Button
        ycomponentEditField       matlab.ui.control.NumericEditField
        ycomponentEditFieldLabel  matlab.ui.control.Label
        xcomponentEditField       matlab.ui.control.NumericEditField
        xcomponentEditFieldLabel  matlab.ui.control.Label
        AngledegEditField         matlab.ui.control.NumericEditField
        AngledegEditFieldLabel    matlab.ui.control.Label
        MagnitudeEditField        matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel   matlab.ui.control.Label
        RUNButton                 matlab.ui.control.Button
        CLEARButton               matlab.ui.control.Button
        VectorDecompositionLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
           mag = app.MagnitudeEditField.Value;
           ang = app.AngledegEditField.Value;

           ang = deg2rad(ang);

           x_comp = mag*cos(ang);
           y_comp = mag*sin(ang);

           app.xcomponentEditField.Value = x_comp;
           app.ycomponentEditField.Value = y_comp;
           
           quiver(app.UIAxes,0,0,x_comp,y_comp,'b','LineWidth',1.5) % MATLAB forum page

           xlim(app.UIAxes,[-mag mag])
           ylim(app.UIAxes,[-mag mag])
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes) % mathworks website
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            splash_screen_g5
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector:')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.VectorDecompositionLabel = uilabel(app.UIFigure);
            app.VectorDecompositionLabel.Text = 'Vector Decomposition';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.MagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude:';
            app.MagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Text = 'Angle (deg.):';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.xcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.xcomponentEditFieldLabel.Text = 'x component:';
            app.xcomponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.xcomponentEditField.Editable = 'off';
            app.ycomponentEditFieldLabel = uilabel(app.UIFigure);
            app.ycomponentEditFieldLabel.Text = 'y component:';
            app.ycomponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ycomponentEditField.Editable = 'off';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vector_decomp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end