classdef whackamole < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HighScoreEditField       matlab.ui.control.NumericEditField
        HighScoreEditFieldLabel  matlab.ui.control.Label
        AttemptEditField         matlab.ui.control.NumericEditField
        AttemptEditFieldLabel    matlab.ui.control.Label
        ScoreEditField           matlab.ui.control.NumericEditField
        ScoreEditFieldLabel      matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            t = timer;
            t.Period = 0.5;
            t.ExecutionMode = "fixedRate";
            t.TasksToExecute = 60;
            t.TimerFcn = @(~,thisEvent) toggleButton(app);
            if app.ScoreEditField.Value > app.HighScoreEditField.Value
                app.HighScoreEditField.Value = app.ScoreEditField.Value
            end
           app.ScoreEditField.Value = 0
          app.Label_2.Text = num2str(30);
          app.AttemptEditField.Value = (app.AttemptEditField.Value) + 1;
            start(t)

        end

        % Button pushed function: Button, Button_10, Button_2, Button_3, 
        % ...and 5 other components
        function ButtonPushed(app, event)
            if num2str(app.Label_2.Text) > 0
            app.ScoreEditField.Value = (app.ScoreEditField.Value) + 15;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'mole.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 'mole.jpg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Icon = 'mole.jpg';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Icon = 'mole.jpg';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_6.Icon = 'mole.jpg';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Icon = 'mole.jpg';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_8.Icon = 'mole.jpg';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_9.Icon = 'mole.jpg';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_10.Icon = 'mole.jpg';
            app.Button_10.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '30';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttemptEditFieldLabel = uilabel(app.UIFigure);
            app.AttemptEditFieldLabel.Text = 'Attempt';
            app.AttemptEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighScoreEditFieldLabel = uilabel(app.UIFigure);
            app.HighScoreEditFieldLabel.Text = 'High Score!';
            app.HighScoreEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whackamole

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end